classdef GridLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GridLaboratoryUIFigure       matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MotorParametersTab           matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        Panel_2                      matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        FrequencySliderLabel         matlab.ui.control.Label
        FrequencySlider              matlab.ui.control.Slider
        EditFrequence                matlab.ui.control.EditField
        PolesSpinnerLabel            matlab.ui.control.Label
        PolesSpinner                 matlab.ui.control.Spinner
        Image                        matlab.ui.control.Image
        PHASEINDUCTIONMOTORLabel     matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        GridVoltage                  matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        VrmsGridVKnob                matlab.ui.control.Knob
        VbLamp_11                    matlab.ui.control.Lamp
        VaLabel                      matlab.ui.control.Label
        VbLamp_12                    matlab.ui.control.Lamp
        VbLamp_13                    matlab.ui.control.Lamp
        VbLabel                      matlab.ui.control.Label
        VcLabel                      matlab.ui.control.Label
        VrmsEditField                matlab.ui.control.EditField
        VrmsEditFieldLabel           matlab.ui.control.Label
        ParametersPanel              matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        RsEditFieldLabel             matlab.ui.control.Label
        RsEditField                  matlab.ui.control.NumericEditField
        XsEditFieldLabel             matlab.ui.control.Label
        XsEditField                  matlab.ui.control.NumericEditField
        XmEditFieldLabel             matlab.ui.control.Label
        XmEditField                  matlab.ui.control.NumericEditField
        XrEditFieldLabel             matlab.ui.control.Label
        XrEditField                  matlab.ui.control.NumericEditField
        RrEditFieldLabel             matlab.ui.control.Label
        RrEditField                  matlab.ui.control.NumericEditField
        StartButton                  matlab.ui.control.Button
        SetParametersButton          matlab.ui.control.Button
        MotorLoadPanel               matlab.ui.container.Panel
        GridLayout16                 matlab.ui.container.GridLayout
        LoadKnob_2Label              matlab.ui.control.Label
        LoadTorqueKnob               matlab.ui.control.Knob
        NmLabel                      matlab.ui.control.Label
        LoadTorqueEditField          matlab.ui.control.EditField
        LampLabel                    matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        PresetMotorButton            matlab.ui.control.Button
        InformationButton            matlab.ui.control.Button
        EraseAllButton               matlab.ui.control.Button
        SetParametersMessageBox      matlab.ui.control.EditField
        GraphicalResultsTab          matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        ExportDropDownLabel          matlab.ui.control.Label
        ExportDropDown               matlab.ui.control.DropDown
        TabGroup2                    matlab.ui.container.TabGroup
        GeneralResultsTab            matlab.ui.container.Tab
        GridLayout23                 matlab.ui.container.GridLayout
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes2_2                    matlab.ui.control.UIAxes
        UIAxes2_3                    matlab.ui.control.UIAxes
        ElectromagneticTorqueTab     matlab.ui.container.Tab
        GridLayout20                 matlab.ui.container.GridLayout
        UIAxes_EM_torque             matlab.ui.control.UIAxes
        SpeedTab                     matlab.ui.container.Tab
        GridLayout25                 matlab.ui.container.GridLayout
        UIAxes5                      matlab.ui.control.UIAxes
        PhaseCurrentsStatorTab       matlab.ui.container.Tab
        GridLayout22                 matlab.ui.container.GridLayout
        UIAxes3                      matlab.ui.control.UIAxes
        VoltageTab                   matlab.ui.container.Tab
        GridLayout24                 matlab.ui.container.GridLayout
        UIAxes4                      matlab.ui.control.UIAxes
        ReadMeTab                    matlab.ui.container.Tab
        GridLayout21                 matlab.ui.container.GridLayout
        ModeTextAreaLabel            matlab.ui.control.Label
        ModeTextArea                 matlab.ui.control.TextArea
        MotorTextAreaLabel           matlab.ui.control.Label
        MotorTextArea                matlab.ui.control.TextArea
        PWMTextAreaLabel             matlab.ui.control.Label
        PWMTextArea                  matlab.ui.control.TextArea
        MotorTextArea_2Label         matlab.ui.control.Label
        MotorTextArea_2              matlab.ui.control.TextArea
        RelevantInformationTextAreaLabel  matlab.ui.control.Label
        RelevantInformationTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        callingApp % Description
        simulated % Description
    end
    
    methods (Access = private)
        
        function getModelValues(app)
            %vDCFromModel =  get_param([bdroot,'/Vdc'],'Value');
            
            %app.VrmsEditField.Value =  str2double(vDCFromModel);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
           
            app.callingApp = mainApp;
            movegui(app.GridLaboratoryUIFigure,'center');
            load_system('simulation\RTInductionMotorFeb21.slx');   %loads the system into temp memory
            % so that the command 'bdroot' returns the last Simulink model loaded
            set_param([bdroot,'/SourceSelect'],'Value',num2str(0));
            %getModelValues(app);
        end

        % Value changed function: PolesSpinner
        function PolesSpinnerValueChanging(app, event)
            changingValue = event.Value; % reads the value changed in the event happening
            
            polesSpinner = num2str(changingValue); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
            
            set_param([bdroot,'/Motor Model/Poles'],'Value', polesSpinner);
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
            changingValue = event.Value; % reads the value changed in the event happening
             
            freqSlider = num2str(round(changingValue,1)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
            % round() avoids gettings values like 45.4168751
           
            set_param([bdroot,'/Motor Model/Freq'],'Value', freqSlider);
            app.EditFrequence.Value = freqSlider;
        end

        % Value changed function: EditFrequence
        function EditFrequenceValueChanged(app, event)
            valueEdit = app.EditFrequence.Value;
           
            set_param([bdroot,'/Motor Model/Freq'],'Value', num2str(valueEdit));
            
            app.FrequencySlider.Value=str2double(valueEdit);
            
        end

        % Value changed function: VrmsGridVKnob
        function VrmsGridValueChanging(app, event)
            changingValue = event.Value;
            
            voltKnob = num2str(round(changingValue,1)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/GridVoltage'],'Value', voltKnob);
           
%             GridVoltFromModel =  get_param([bdroot,'/GridVoltage'],'Value');
%             
%             app.VrmsEditField.Value =  str2double(GridVoltFromModel);

            app.VrmsEditField.Value =  voltKnob;
        end

        % Callback function: VrmsEditField, VrmsEditField
        function VrmsEditFieldValueChanged(app, event)
            valueEdit = app.VrmsEditField.Value;
           
            set_param([bdroot,'/GridVoltage'],'Value', num2str(valueEdit));
            
            app.VrmsGridVKnob.Value=str2double(valueEdit);
        end

        % Value changed function: LoadTorqueKnob
        function LoadTorqueKnobValueChanging(app, event)
            changingValue = event.Value;
            
            loadKnob = num2str(round(changingValue,1)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/Motor Model/Input Torque'],'Value', loadKnob);
           
            app.LoadTorqueEditField.Value =  loadKnob;
        end

        % Value changed function: LoadTorqueEditField
        function LoadTorqueEditFieldValueChanged(app, event)
            valueEdit = app.LoadTorqueEditField.Value;
           
            set_param([bdroot,'/Motor Model/Input Torque'],'Value', num2str(valueEdit));
            
            app.LoadTorqueKnob.Value=str2double(valueEdit);
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            %app.SetParametersButton.Enable = 'off';        
           
                       
            Rs = num2str(app.RsEditField.Value);
            Ls = num2str(app.XsEditField.Value);
            Rr = num2str(app.RrEditField.Value);           
            Lr = num2str(app.XrEditField.Value);
            Lm = num2str(app.XmEditField.Value);
            
            set_param([bdroot,'/Motor Model/Rs (ohms)'],'Value',Rs);
            set_param([bdroot,'/Motor Model/Ls (H)'],'Value',Ls); 
            set_param([bdroot,'/Motor Model/Rr (ohms)'],'Value',Rr);
            set_param([bdroot,'/Motor Model/Lr (H)'],'Value',Lr);
            set_param([bdroot,'/Motor Model/Lm (H)'],'Value',Lm);
            
            save_system('simulation\RTInductionMotorFeb21.slx');
            app.SetParametersMessageBox.Visible=true
            
             app.SetParametersMessageBox.Value='Parameters sent to model';
       
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable ='off';
            %save_system('simulation/RTInductionMotorFeb21.slx');
            save_system('simulation\RTInductionMotorFeb21.slx');
            app.simulated = sim("simulation\RTInductionMotorFeb21.slx");
      
            if (get_param(bdroot,'SimulationStatus') == 'stopped')
                app.StartButton.Enable ='on';
            end
        end

        % Close request function: GridLaboratoryUIFigure
        function GridLaboratoryUIFigureCloseRequest(app, event)
            app.callingApp.Laboratory1Button.Enable = 'on';
            delete(app)
            
        end

        % Button pushed function: InformationButton
        function InformationButtonPushed(app, event)
            
        end

        % Button pushed function: EraseAllButton
        function EraseAllButtonPushed(app, event)
            app.RsEditField.Value=0;
            app.XsEditField.Value=0;
            app.XmEditField.Value=0;
            app.XrEditField.Value=0;
            app.RrEditField.Value=0;
            app.FrequencySlider.Value=0;
            app.LoadTorqueKnob.Value=0;
            app.VrmsGridVKnob.Value=0;
            app.PolesSpinner.Value=0;
        end

        % Button pushed function: PresetMotorButton
        function PresetMotorButtonPushed(app, event)
            app.RsEditField.Value=1;
            app.XsEditField.Value=0.003883;
            app.XmEditField.Value=0.1485;
            app.XrEditField.Value=0.009061;
            app.RrEditField.Value=1.06;
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GridLaboratoryUIFigure = uifigure('Visible', 'off');
            app.GridLaboratoryUIFigure.Name = 'Grid Laboratory';
            app.GridLaboratoryUIFigure.CloseRequestFcn = createCallbackFcn(app, @GridLaboratoryUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.GridLaboratoryUIFigure);
            app.MotorParametersTab = uitab(app.TabGroup);
            app.MotorParametersTab.Title = 'Motor Parameters';
            app.GridLayout5 = uigridlayout(app.MotorParametersTab);
            app.Panel_2 = uipanel(app.GridLayout5);
            app.GridLayout7 = uigridlayout(app.Panel_2);
            app.FrequencySliderLabel = uilabel(app.GridLayout7);
            app.FrequencySliderLabel.Text = 'Frequency ';
            app.FrequencySlider = uislider(app.GridLayout7);
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.FrequencySlider.Value = 30;
            app.EditFrequence = uieditfield(app.GridLayout7, 'text');
            app.EditFrequence.ValueChangedFcn = createCallbackFcn(app, @EditFrequenceValueChanged, true);
            app.PolesSpinnerLabel = uilabel(app.GridLayout7);
            app.PolesSpinnerLabel.Text = 'Poles';
            app.PolesSpinner = uispinner(app.GridLayout7);
            app.PolesSpinner.Limits = [0 Inf];
            app.PolesSpinner.ValueChangedFcn = createCallbackFcn(app, @PolesSpinnerValueChanging, true);
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = 'EquivalentCircuitDiagram.png';
            app.PHASEINDUCTIONMOTORLabel = uilabel(app.GridLayout5);
            app.PHASEINDUCTIONMOTORLabel.Text = '3-PHASE INDUCTION MOTOR';
            app.TextArea = uitextarea(app.GridLayout5);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'* The values shown are pre-set values. They can be changed at any given moment during the lab experiment'};
            app.GridVoltage = uipanel(app.GridLayout5);
            app.GridVoltage.Title = 'Grid 3-Phase Voltage';
            app.GridLayout10 = uigridlayout(app.GridVoltage);
            app.VrmsGridVKnob = uiknob(app.GridLayout10, 'continuous');
            app.VrmsGridVKnob.Limits = [0 240];
            app.VrmsGridVKnob.ValueChangedFcn = createCallbackFcn(app, @VrmsGridValueChanging, true);
            app.VbLamp_11 = uilamp(app.GridLayout10);
            app.VaLabel = uilabel(app.GridLayout10);
            app.VaLabel.Text = 'Va';
            app.VbLamp_12 = uilamp(app.GridLayout10);
            app.VbLamp_13 = uilamp(app.GridLayout10);
            app.VbLabel = uilabel(app.GridLayout10);
            app.VbLabel.Text = 'Vb';
            app.VcLabel = uilabel(app.GridLayout10);
            app.VcLabel.Text = 'Vc';
            app.VrmsEditField = uieditfield(app.GridLayout10, 'text');
            app.VrmsEditField.ValueChangedFcn = createCallbackFcn(app, @VrmsEditFieldValueChanged, true);
            app.VrmsEditField.ValueChangingFcn = createCallbackFcn(app, @VrmsEditFieldValueChanged, true);
            app.VrmsEditFieldLabel = uilabel(app.GridLayout10);
            app.VrmsEditFieldLabel.Text = 'Vrms';
            app.ParametersPanel = uipanel(app.GridLayout5);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout6 = uigridlayout(app.ParametersPanel);
            app.RsEditFieldLabel = uilabel(app.GridLayout6);
            app.RsEditFieldLabel.Text = 'Rs';
            app.RsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XsEditFieldLabel = uilabel(app.GridLayout6);
            app.XsEditFieldLabel.Text = 'Xs';
            app.XsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XmEditFieldLabel = uilabel(app.GridLayout6);
            app.XmEditFieldLabel.Text = 'Xm';
            app.XmEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XrEditFieldLabel = uilabel(app.GridLayout6);
            app.XrEditFieldLabel.Text = 'Xr';
            app.XrEditField = uieditfield(app.GridLayout6, 'numeric');
            app.RrEditFieldLabel = uilabel(app.GridLayout6);
            app.RrEditFieldLabel.Text = 'Rr''';
            app.RrEditField = uieditfield(app.GridLayout6, 'numeric');
            app.StartButton = uibutton(app.GridLayout5, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SetParametersButton = uibutton(app.GridLayout5, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.MotorLoadPanel = uipanel(app.GridLayout5);
            app.MotorLoadPanel.Title = 'Motor Load';
            app.GridLayout16 = uigridlayout(app.MotorLoadPanel);
            app.LoadKnob_2Label = uilabel(app.GridLayout16);
            app.LoadKnob_2Label.Text = 'Load';
            app.LoadTorqueKnob = uiknob(app.GridLayout16, 'continuous');
            app.LoadTorqueKnob.ValueChangedFcn = createCallbackFcn(app, @LoadTorqueKnobValueChanging, true);
            app.NmLabel = uilabel(app.GridLayout16);
            app.NmLabel.Text = 'N*m';
            app.LoadTorqueEditField = uieditfield(app.GridLayout16, 'text');
            app.LoadTorqueEditField.ValueChangedFcn = createCallbackFcn(app, @LoadTorqueEditFieldValueChanged, true);
            app.LampLabel = uilabel(app.GridLayout16);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.GridLayout16);
            app.PresetMotorButton = uibutton(app.GridLayout5, 'push');
            app.PresetMotorButton.ButtonPushedFcn = createCallbackFcn(app, @PresetMotorButtonPushed, true);
            app.PresetMotorButton.Text = 'Preset Motor ';
            app.InformationButton = uibutton(app.GridLayout5, 'push');
            app.InformationButton.ButtonPushedFcn = createCallbackFcn(app, @InformationButtonPushed, true);
            app.InformationButton.Text = 'Information';
            app.EraseAllButton = uibutton(app.GridLayout5, 'push');
            app.EraseAllButton.ButtonPushedFcn = createCallbackFcn(app, @EraseAllButtonPushed, true);
            app.EraseAllButton.Text = 'Erase All';
            app.SetParametersMessageBox = uieditfield(app.GridLayout5, 'text');
            app.SetParametersMessageBox.Editable = 'off';
            app.SetParametersMessageBox.Visible = 'off';
            app.GraphicalResultsTab = uitab(app.TabGroup);
            app.GraphicalResultsTab.Title = 'Graphical Results';
            app.GridLayout4 = uigridlayout(app.GraphicalResultsTab);
            app.ExportDropDownLabel = uilabel(app.GridLayout4);
            app.ExportDropDownLabel.Text = 'Export';
            app.ExportDropDown = uidropdown(app.GridLayout4);
            app.ExportDropDown.Items = {'Data (Excel)', 'Data (txt)', 'Images (pdf)', 'Images (word)'};
            app.ExportDropDown.Value = 'Data (Excel)';
            app.TabGroup2 = uitabgroup(app.GridLayout4);
            app.GeneralResultsTab = uitab(app.TabGroup2);
            app.GeneralResultsTab.Title = 'General Results';
            app.GridLayout23 = uigridlayout(app.GeneralResultsTab);
            app.UIAxes = uiaxes(app.GridLayout23);
            title(app.UIAxes, 'Voltage')
            xlabel(app.UIAxes, 'T(s)')
            ylabel(app.UIAxes, 'V')
            app.UIAxes2 = uiaxes(app.GridLayout23);
            title(app.UIAxes2, 'Current')
            xlabel(app.UIAxes2, 'T(s)')
            ylabel(app.UIAxes2, 'A')
            app.UIAxes2_2 = uiaxes(app.GridLayout23);
            title(app.UIAxes2_2, 'Speed')
            xlabel(app.UIAxes2_2, 'T(s)')
            ylabel(app.UIAxes2_2, 'RPM')
            app.UIAxes2_3 = uiaxes(app.GridLayout23);
            title(app.UIAxes2_3, 'Torque')
            xlabel(app.UIAxes2_3, 'T(s)')
            ylabel(app.UIAxes2_3, 'N.m')
            app.ElectromagneticTorqueTab = uitab(app.TabGroup2);
            app.ElectromagneticTorqueTab.Title = 'Electro-magnetic Torque';
            app.GridLayout20 = uigridlayout(app.ElectromagneticTorqueTab);
            app.UIAxes_EM_torque = uiaxes(app.GridLayout20);
            title(app.UIAxes_EM_torque, 'Electro-Magnetic Torque')
            xlabel(app.UIAxes_EM_torque, 'Time (s)')
            ylabel(app.UIAxes_EM_torque, 'Amps')
            app.SpeedTab = uitab(app.TabGroup2);
            app.SpeedTab.Title = 'Speed';
            app.GridLayout25 = uigridlayout(app.SpeedTab);
            app.UIAxes5 = uiaxes(app.GridLayout25);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.PhaseCurrentsStatorTab = uitab(app.TabGroup2);
            app.PhaseCurrentsStatorTab.Title = 'Phase Currents (Stator)';
            app.GridLayout22 = uigridlayout(app.PhaseCurrentsStatorTab);
            app.UIAxes3 = uiaxes(app.GridLayout22);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.VoltageTab = uitab(app.TabGroup2);
            app.VoltageTab.Title = 'Voltage';
            app.GridLayout24 = uigridlayout(app.VoltageTab);
            app.UIAxes4 = uiaxes(app.GridLayout24);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.ReadMeTab = uitab(app.TabGroup);
            app.ReadMeTab.Title = 'Read Me';
            app.GridLayout21 = uigridlayout(app.ReadMeTab);
            app.ModeTextAreaLabel = uilabel(app.GridLayout21);
            app.ModeTextAreaLabel.Text = 'Mode';
            app.ModeTextArea = uitextarea(app.GridLayout21);
            app.ModeTextArea.Editable = 'off';
            app.ModeTextArea.Value = {'The mode pannel is composed of two lights: green and red. The green corresponds to the working mode or "on" mode. The purpose of this app is to emulate an electric drive motor. This visual only shows the student if the speed is correct, such that they do not go into generation mode.'};
            app.MotorTextAreaLabel = uilabel(app.GridLayout21);
            app.MotorTextAreaLabel.Text = 'Motor';
            app.MotorTextArea = uitextarea(app.GridLayout21);
            app.MotorTextArea.Editable = 'off';
            app.MotorTextArea.Value = {'In order to get visual results, we first need to inputs the parameters of the motor. Once they have been set and saved, the student will be able to go into the "Graphical Results". If the button "set parameters" has not been set, the latest will not open. (To be implemented once I found out how to send the information to the model).'};
            app.PWMTextAreaLabel = uilabel(app.GridLayout21);
            app.PWMTextAreaLabel.Text = 'PWM';
            app.PWMTextArea = uitextarea(app.GridLayout21);
            app.PWMTextArea.Editable = 'off';
            app.MotorTextArea_2Label = uilabel(app.GridLayout21);
            app.MotorTextArea_2Label.Text = 'Motor';
            app.MotorTextArea_2 = uitextarea(app.GridLayout21);
            app.MotorTextArea_2.Editable = 'off';
            app.MotorTextArea_2.Value = {'In order to get visual results, we first need to inputs the parameters of the motor. Once they have been set and saved, the student will be able to go into the "Graphical Results". If the button "set parameters" has not been set, the latest will not open. (To be implemented once I found out how to send the information to the model).'};
            app.RelevantInformationTextAreaLabel = uilabel(app.GridLayout21);
            app.RelevantInformationTextAreaLabel.Text = 'Relevant Information';
            app.RelevantInformationTextArea = uitextarea(app.GridLayout21);
            app.RelevantInformationTextArea.Editable = 'off';
            app.RelevantInformationTextArea.Value = {'Concordia Security'; ''; '514-848-3717'; ''; 'Dial 1 for urgent situations'; 'Dial 2 for non-urgent situations'; ''; 'In case of emergency, contact 9-1-1'; ''; 'For Licensing questions: inquiry@eruditio.com'};
            % Show the figure after all components are created
            app.GridLaboratoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GridLab(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GridLaboratoryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GridLaboratoryUIFigure)
        end
    end
end