classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        Image              matlab.ui.control.Image
        TextArea           matlab.ui.control.TextArea
        Laboratory1Button  matlab.ui.control.Button
        Laboratory2Button  matlab.ui.control.Button
        Laboratory3Button  matlab.ui.control.Button
        TextArea_6         matlab.ui.control.TextArea
        TextArea_5         matlab.ui.control.TextArea
        TextArea_4         matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        Lab1Object % Description
        Lab2Object % Description
        Lab3Object % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Laboratory1Button
        function Laboratory1ButtonPushed(app, event)
            app.Laboratory1Button.Enable = 'off';
            app.Lab1Object = GridLab(app);
        end

        % Button pushed function: Laboratory2Button
        function Laboratory2ButtonPushed(app, event)
            app.Laboratory2Button.Enable = 'off';
            app.Lab2Object = VFLab(app);
        end

        % Button pushed function: Laboratory3Button
        function Laboratory3ButtonPushed(app, event)
            app.Laboratory3Button.Enable = 'off';
            app.Lab3Object = VectorLab(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app.Lab1Object)
            delete(app.Lab2Object)
            delete(app.Lab3Object)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'Capture.PNG';
            app.TextArea = uitextarea(app.GridLayout);
            app.Laboratory1Button = uibutton(app.GridLayout, 'push');
            app.Laboratory1Button.ButtonPushedFcn = createCallbackFcn(app, @Laboratory1ButtonPushed, true);
            app.Laboratory1Button.Text = 'Laboratory 1';
            app.Laboratory2Button = uibutton(app.GridLayout, 'push');
            app.Laboratory2Button.ButtonPushedFcn = createCallbackFcn(app, @Laboratory2ButtonPushed, true);
            app.Laboratory2Button.Text = 'Laboratory 2';
            app.Laboratory3Button = uibutton(app.GridLayout, 'push');
            app.Laboratory3Button.ButtonPushedFcn = createCallbackFcn(app, @Laboratory3ButtonPushed, true);
            app.Laboratory3Button.Text = 'Laboratory 3';
            app.TextArea_6 = uitextarea(app.GridLayout);
            app.TextArea_6.Value = {'Grid Laboratory'};
            app.TextArea_5 = uitextarea(app.GridLayout);
            app.TextArea_5.Value = {'VF Laboratory'};
            app.TextArea_4 = uitextarea(app.GridLayout);
            app.TextArea_4.Value = {'Vector Laboratory'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end